classdef OPP < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      UIFigure        matlab.ui.Figure
      FileMenu        matlab.ui.container.Menu
      ToolsMenu       matlab.ui.container.Menu
      ToysMenu        matlab.ui.container.Menu
      TestMenu        matlab.ui.container.Menu
      VideoMenu       matlab.ui.container.Menu
      VideoMenu_Test  matlab.ui.container.Menu
      AudioMenu       matlab.ui.container.Menu
      AudioMenu_Test  matlab.ui.container.Menu
      Label           matlab.ui.control.Label
      ListBox         matlab.ui.control.ListBox
      ListBoxLabel    matlab.ui.control.Label
      UITable         matlab.ui.control.Table
      LogPanel        matlab.ui.container.Panel
      EditField       matlab.ui.control.EditField
      ToolsPanel      matlab.ui.container.Panel
      MixerLamp       matlab.ui.control.Lamp
      MixerLampLabel  matlab.ui.control.Label
      AudioLamp       matlab.ui.control.Lamp
      AudioLampLabel  matlab.ui.control.Label
      VideoLamp       matlab.ui.control.Lamp
      VideoLampLabel  matlab.ui.control.Label
      ToysLamp        matlab.ui.control.Lamp
      ToysLampLabel   matlab.ui.control.Label
   end

   
   properties (Access = public)
      EndFcn = [];
      SynthFcn = [];
   end
   
   properties (Access = private)
      hOntrak = [];
      hStreamer = [];
      hVLC = [];      
      hMixer = [];
      errorColor = [1 0 0];
      successColor = [0 1 0];
   end
   
   methods (Access = private)

      function InitializeOntrak(app)
         try
            app.hOntrak = Ontrak();
            app.hOntrak = app.hOntrak.Initialize();
            app.hOntrak.TurnOff();
            app.ToysLamp.Color = app.successColor;
         catch
            app.ToysLamp.Color = app.errorColor;
%             waitfor(msgbox('Failed to connect. Is Ontrak device connected?', 'OPP', 'error'));
         end
      end

      %--------------------------------------------------------------------
      function InitializeAudioStreamer(app)
         try
            isLoaded = false;
            asm = System.AppDomain.CurrentDomain.GetAssemblies;
            for k = 1:asm.Length
               isLoaded = startsWith(char(asm(k).FullName), 'OPP.AudioStreamer');
               if isLoaded, break; end
            end

            if ~isLoaded
               NET.addAssembly(fullfile('D:\Development\Arenberg\OPP\jga_opp\LabVIEW\OPP.AudioStreamer\Build', 'OPP.AudioStreamer.dll'));
            end

            app.hStreamer = OPP.AudioStreamer;
            app.hStreamer.Initialize(false);
            app.AudioLamp.Color = app.successColor;
         catch
            app.AudioLamp.Color = app.errorColor;
         end
      end

      %--------------------------------------------------------------------
      function InitializeVLC(app)
         try
            app.hVLC = VLC();
            app.hVLC.Status();
            app.VideoLamp.Color = app.successColor;
         catch ex
            app.hVLC = [];
            app.VideoLamp.Color = app.errorColor;
         end
      end

      %--------------------------------------------------------------------
      function InitializeMixer(app)
%          try
%             app.hMixer = Mixer();
%             if app.hMixer.IsConnected()
%                app.MixerLamp.Color = app.successColor;
%             else
%                app.MixerLamp.Color = app.errorColor;
%             end
%          catch ex
%             app.MixerLamp.Color = app.errorColor;
%          end
         try
            isLoaded = false;
            asm = System.AppDomain.CurrentDomain.GetAssemblies;
            for k = 1:asm.Length
               isLoaded = startsWith(char(asm(k).FullName), 'OPP.Mixer');
               if isLoaded, break; end
            end

            if ~isLoaded
               NET.addAssembly(fullfile('D:\Development\Arenberg\OPP\jga_opp\LabVIEW\OPP.Mixer\Build', 'OPP.Mixer.dll'));
            end

            app.hMixer = OPP.Mixer;
            app.hMixer.Open();
            app.MixerLamp.Color = app.successColor;
         catch
            app.MixerLamp.Color = app.errorColor;
         end
      end

      %--------------------------------------------------------------------
      function SetAudioStream(app)
         if isempty(app.SynthFcn), return; end
         
         stim = app.SynthFcn();
         
         zeroSignal = zeros(48000, 1);

         app.hStreamer.SetConfig('Out 1-24 (MOTU Pro Audio)', 'Microphone (HD Pro Webcam C920)', 48000);
         app.hStreamer.SetNumReps(3);

         y = zeroSignal;
         y(1:length(stim.caregiver.waveform)) = stim.caregiver.waveform;
         app.hStreamer.SetSignal('caregiver', stim.caregiver.name, y);

         y = zeroSignal;
         y(1:length(stim.waver.waveform)) = stim.waver.waveform;
         app.hStreamer.SetSignal('waver', stim.waver.name, y);

         y = zeroSignal;
         y(1:length(stim.background.waveform)) = stim.background.waveform;
         app.hStreamer.SetSignal('background', stim.background.name, y);

         y = zeroSignal;
         y(1:length(stim.signal.waveform)) = stim.signal.waveform;
         app.hStreamer.SetSignal('signal', stim.signal.name, y);
      end
end
   

   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFcn(app, varargin)
         % Restore figure position
         origin = getpref('OPP', 'Position', []);
         if ~isempty(origin)
            pos = app.UIFigure.Position;
            pos(1:2) = origin;
            app.UIFigure.Position = pos;
         end         

         d = uiprogressdlg(app.UIFigure, 'Title', 'Please wait', 'Indeterminate', 'on');
         drawnow;

         for k = 1:2:length(varargin)
            app.(varargin{k}) = varargin{k+1};
         end

         d.Message = 'Initializing Ontrak...';
         app.InitializeOntrak();
         drawnow;

         d.Message = 'Initializing video...';
         app.InitializeVLC();
         drawnow;

         d.Message = 'Initializing audio streamer...';
         app.InitializeAudioStreamer();
         drawnow;

         d.Message = 'Initializing mixer...';
         app.InitializeMixer();
         drawnow;

         delete(d);

         app.SetAudioStream();

      end

      % Close request function: UIFigure
      function UIFigureCloseRequest(app, event)
         uiresume(app.UIFigure);
         pos = app.UIFigure.Position;
         setpref('OPP', 'Position', pos(1:2));

         if ~isempty(app.hOntrak)
            delete(app.hOntrak);
         end

         if ~isempty(app.hVLC)
            app.hVLC.quit();
         end

         if ~isempty(app.hStreamer)
            app.hStreamer.Close();
         end

         if ~isempty(app.hMixer)
            app.hMixer.Close();
         end

         if ~isempty(app.EndFcn)
            app.EndFcn();
         end
         
         delete(app)
      end

      % Button down function: ToolsPanel
      function ToolsPanelButtonDown(app, event)

      end

      % Menu selected function: TestMenu
      function TestMenuSelected(app, event)
         h = OntrakTest(app.hOntrak);
         uiwait(h.UIFigure);
      end

      % Menu selected function: AudioMenu_Test
      function AudioMenu_TestSelected(app, event)
         h = AudioStreamerTest(app.hStreamer);
         uiwait(h.UIFigure);
      end

      % Menu selected function: VideoMenu_Test
      function VideoMenu_TestSelected(app, event)
         if ~isempty(app.hVLC)
            h = VLCTest(app.hVLC);
            uiwait(h.UIFigure);         
         end
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.UIFigure = uifigure('Visible', 'off');
         app.UIFigure.Name = 'OPP';
         app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
         app.FileMenu = uimenu(app.UIFigure);
         app.FileMenu.Text = 'File';
         app.ToolsMenu = uimenu(app.UIFigure);
         app.ToolsMenu.Text = 'Tools';
         app.ToysMenu = uimenu(app.ToolsMenu);
         app.ToysMenu.Text = 'Toys';
         app.TestMenu = uimenu(app.ToysMenu);
         app.TestMenu.MenuSelectedFcn = createCallbackFcn(app, @TestMenuSelected, true);
         app.TestMenu.Text = 'Test';
         app.VideoMenu = uimenu(app.ToolsMenu);
         app.VideoMenu.Text = 'Video';
         app.VideoMenu_Test = uimenu(app.VideoMenu);
         app.VideoMenu_Test.MenuSelectedFcn = createCallbackFcn(app, @VideoMenu_TestSelected, true);
         app.VideoMenu_Test.Text = 'Test';
         app.AudioMenu = uimenu(app.ToolsMenu);
         app.AudioMenu.Text = 'Audio';
         app.AudioMenu_Test = uimenu(app.AudioMenu);
         app.AudioMenu_Test.MenuSelectedFcn = createCallbackFcn(app, @AudioMenu_TestSelected, true);
         app.AudioMenu_Test.Text = 'Test';
         app.ToolsPanel = uipanel(app.UIFigure);
         app.ToolsPanel.Title = 'Tools';
         app.ToolsPanel.ButtonDownFcn = createCallbackFcn(app, @ToolsPanelButtonDown, true);
         app.ToysLampLabel = uilabel(app.ToolsPanel);
         app.ToysLampLabel.Text = 'Toys';
         app.ToysLamp = uilamp(app.ToolsPanel);
         app.VideoLampLabel = uilabel(app.ToolsPanel);
         app.VideoLampLabel.Text = 'Video';
         app.VideoLamp = uilamp(app.ToolsPanel);
         app.AudioLampLabel = uilabel(app.ToolsPanel);
         app.AudioLampLabel.Text = 'Audio';
         app.AudioLamp = uilamp(app.ToolsPanel);
         app.MixerLampLabel = uilabel(app.ToolsPanel);
         app.MixerLampLabel.Text = 'Mixer';
         app.MixerLamp = uilamp(app.ToolsPanel);
         app.LogPanel = uipanel(app.UIFigure);
         app.LogPanel.Title = 'Log';
         app.EditField = uieditfield(app.LogPanel, 'text');
         app.EditField.Editable = 'off';
         app.UITable = uitable(app.UIFigure);
         app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
         app.UITable.RowName = {};
         app.ListBoxLabel = uilabel(app.UIFigure);
         app.ListBoxLabel.Text = 'List Box';
         app.ListBox = uilistbox(app.UIFigure);
         app.Label = uilabel(app.UIFigure);
         % Show the figure after all components are created
         app.UIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = OPP(varargin)

         % Create UIFigure and components
         createComponents(app)

         % Register the app with App Designer
         registerApp(app, app.UIFigure)

         % Execute the startup function
         runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.UIFigure)
      end
   end
end